classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load EMG data at startup (replace 's1.mat' with your file if needed)
            try
                load('s1.mat', 'dsfilt_emg');
                app.EMGData = dsfilt_emg;
                numTasks = size(app.EMGData, 2);
                taskStrings = cell(1, numTasks);
                for i = 1:numTasks
                    taskStrings{i} = sprintf('Task %d', i);
                end
                app.TaskSelectionDropdown.Items = taskStrings;
                app.TaskSelectionDropdown.Value = taskStrings{1}; % Select first task by default

                % Initialize Sensor Checkboxes
                for i = 1:length(app.SensorNames)
                    app.SensorSelectionCheckBoxes(i).Text = app.SensorNames{i};
                    app.SensorSelectionCheckBoxes(i).Value = true; % Select all sensors by default
                end

                app.BandpassFilterSettingsPanel.Visible = 'off'; % Initially hide filter panels
                app.NotchFilterSettingsPanel.Visible = 'off';

                updateEMGPlot(app); % Initial plot with default task and sensors

            catch ME
                uialert(app.UIFigure, ['Error loading EMG data: ', ME.message], 'Startup Error', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end